classdef main_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Histogram                 matlab.ui.container.Tab
        DropDown                  matlab.ui.control.DropDown
        Label_4                   matlab.ui.control.Label
        GrayImage                 matlab.ui.control.Image
        Label_3                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        InputImage                matlab.ui.control.Image
        BtnHistogramEqualization  matlab.ui.control.Button
        HistogramEqualization     matlab.ui.control.Image
        UIAxesGrayHistgram        matlab.ui.control.UIAxes
        Tab2                      matlab.ui.container.Tab
    end


    methods (Access = public)

        % 统计图像灰度直方图
        function histogram = gray_histogram(app, image)

            histogram = zeros(256, 1);
            [height, width] = size(image);

            for x = 1:width
                for y = 1:height
                    pixelValue = image(y, x) + 1;
                    histogram(pixelValue) = histogram(pixelValue) + 1;
                end
            end
        end


        % 绘制灰度直方图数组到坐标轴
        function draw_histogram(app, Histogram)
            % 获取灰度直方图坐标轴组件
            axesHandle = app.UIAxesGrayHistgram;

            % 绘制直方图
            bar(axesHandle, histogram);

        end


        % 直方图均衡化
        function equalized_image  = histogram_equalization(~, image)

            histgram = imhist(image);
            [h, w] = size(image);
            equalized_image = zeros(h, w, 'uint8'); % Initialize as uint8
            s = zeros(256); s(1) = histgram(1);

            for t = 2 : 256
                s(t) = s(t - 1) + histgram(t);
            end

            for x = 1: w
                for y = 1: h
                    equalized_image(y, x) = uint8(255 * s(image(y, x) + 1) / (w * h));

                end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: InputImage
        function InputImageOnClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            imgData = imread(fullFilePath);
            app.InputImage.ImageSource = imgData;
            gray_image = rgb2gray(imgData);
            app.GrayImage.ImageSource =  cat(3, gray_image, gray_image, gray_image);
            histogram = gray_histogram(app, rgb2gray(app.InputImage.ImageSource));
            draw_histogram(app, histogram);
        end

        % Button pushed function: BtnHistogramEqualization
        function BtnHistogramEqualizationButtonPushed(app, event)
            if ~isempty(app.InputImage.ImageSource)

                equalized_image = histogram_equalization(app, rgb2gray(app.InputImage.ImageSource));
                app.HistogramEqualization.ImageSource = cat(3, equalized_image, equalized_image, equalized_image);
            else

                disp('输入图像为空');
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;

            if strcmp(value, '原图')
                histogram = gray_histogram(app, rgb2gray(app.InputImage.ImageSource));
                draw_histogram(app, histogram);
            elseif strcmp(value, '均衡化')
                histogram = gray_histogram(app, app.HistogramEqualization.ImageSource);
                draw_histogram(app, histogram);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Histogram = uitab(app.TabGroup);
            app.Histogram.Title = '直方图处理';
            app.UIAxesGrayHistgram = uiaxes(app.Histogram);
            title(app.UIAxesGrayHistgram, '灰度直方图')
            xlabel(app.UIAxesGrayHistgram, '灰度值')
            ylabel(app.UIAxesGrayHistgram, '频次')
            zlabel(app.UIAxesGrayHistgram, 'Z')
            app.HistogramEqualization = uiimage(app.Histogram);
            app.BtnHistogramEqualization = uibutton(app.Histogram, 'push');
            app.BtnHistogramEqualization.ButtonPushedFcn = createCallbackFcn(app, @BtnHistogramEqualizationButtonPushed, true);
            app.BtnHistogramEqualization.Text = '直方图均衡化';
            app.InputImage = uiimage(app.Histogram);
            app.InputImage.ImageClickedFcn = createCallbackFcn(app, @InputImageOnClicked, true);
            app.Label = uilabel(app.Histogram);
            app.Label.Text = '原始图像';
            app.Label_2 = uilabel(app.Histogram);
            app.Label_2.Text = '直方图均衡化';
            app.Label_3 = uilabel(app.Histogram);
            app.Label_3.Text = '灰度图';
            app.GrayImage = uiimage(app.Histogram);
            app.Label_4 = uilabel(app.Histogram);
            app.Label_4.Text = '选择要显示灰度直方图的图像';
            app.DropDown = uidropdown(app.Histogram);
            app.DropDown.Items = {'原图', '均衡化'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '原图';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_view

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end